% description: 1. Select a folder which contains STL files
% 2. Pick up a STL
% 3. Adjust ratio to reduce faces in the STL file
% 4. Overwrite the STL file
% Copyright 2024 The MathWorks, Inc.
classdef StlResolutionReducer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        RatioSpinnerLabel    matlab.ui.control.Label
        RatioSpinner         matlab.ui.control.Spinner
        STLOverwriteButton   matlab.ui.control.Button
        RatioSlider          matlab.ui.control.Slider
        GridLayout3          matlab.ui.container.GridLayout
        ReducedUIAxes        matlab.ui.control.UIAxes
        PresentUIAxes        matlab.ui.control.UIAxes
        GridLayout2          matlab.ui.container.GridLayout
        ShowPresentCheckBox  matlab.ui.control.CheckBox
        StlFileListBox       matlab.ui.control.ListBox
        FolderSelectButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        tr1 % Present Triangulation
        tr2 % Reduced Triangulation
        filename % Present STL file name, full path
        folder % folder name
        ratio % reducing ratio
        bigFile % File Size to adjust performance
        stlPresense % STL file presence
    end
    
    methods (Access = private)
        
        function results = stlread(app, stlFileName)
            results = true;

            try
                app.tr1 = stlread(stlFileName); %#ok<ADMTHDINV>
                app.filename = stlFileName;
            catch
                results = false;
            end
        end
        
        function results = stlreduce(app, ratio)
            tr1 = app.tr1; %#ok<ADPROPLC>
            results = true;

            try
                [verticesReduced, facesReduced] = reducepatch(tr1.ConnectivityList, tr1.Points, ratio); %#ok<ADPROPLC>
                tr2 = triangulation(verticesReduced, facesReduced); %#ok<ADPROPLC>
                app.tr2 = tr2; %#ok<ADPROPLC,ADPROPLC>
            catch
                results = false;
            end
        end
        
        function results = stlwrite(app)
            stlfilename = app.filename;
            tr2 = app.tr2; %#ok<ADPROP>
            results = true;
            
            try
                stlwrite(tr2,stlfilename) %#ok<ADPROP,ADPROP,ADMTHDINV>
            catch
                results = false;
            end
        end

        function results = showPresentTriangulation(app)

            ax = app.PresentUIAxes;
            results = true;

            if app.ShowPresentCheckBox.Value
                % show triangulation

                tr1 = app.tr1; %#ok<ADPROP>

                try
                    trisurf(tr1, 'Parent', ax) %#ok<ADPROP>
                    xlim(ax,"auto")
                    ylim(ax,"auto")
                    xticks(ax,"auto")
                    yticks(ax,"auto")
                    xticklabels(ax,"auto")
                    yticklabels(ax,"auto")
                    xlabel(ax,"X")
                    ylabel(ax,"Y")
                    axis(ax,"equal")
                catch
                    results = false;
                    app.showInvalid(ax);
                end

            else
                % show message to adjust performance

                % set 2D view
                plot(ax,0.0)
                view(ax,2);
                % UIAxes range
                xlim(ax, [0 1]);
                ylim(ax, [0 1]);
                % No label
                ax.XTick = [];
                ax.YTick = [];
                ax.XLabel.String = '';
                ax.YLabel.String = '';
                ax.Box = 'on';

                % Align text box at the center of the UIAxes
                text(ax, 0.5, 0.5, 'Disable', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 14);

            end
        end

        function results = showReducedTriangluation(app)
            tr2 = app.tr2; %#ok<ADPROP>
            ax = app.ReducedUIAxes;
            results = true;

            try
                trisurf(tr2, 'Parent', ax) %#ok<ADPROP>
                xlim(ax,"auto")
                ylim(ax,"auto")
                xticks(ax,"auto")
                yticks(ax,"auto")
                xticklabels(ax,"auto")
                yticklabels(ax,"auto")
                xlabel(ax,"X")
                ylabel(ax,"Y")
                axis(ax,"equal")
            catch
                results = false;
                app.showInvalid(ax);
            end
        end
        
        function updateFileList(app, folderPath)

            stlFiles = dir(fullfile(folderPath, '*.stl')); % get stl file names
            if ~ispc
                % file name case sensitive
                stlFilesUpper = dir(fullfile(folderPath, '*.STL')); % get stl file names
                stlFiles = [stlFiles; stlFilesUpper];
            end

            app.stlPresense = ~isempty(stlFiles);

            if app.stlPresense
                fileList = {stlFiles.name}; % get file name list

                app.StlFileListBox.Items = fileList; % update ListBox
                app.folder = folderPath;    % update folder path
            else
                app.StlFileListBox.Items = "No STL files";
                app.showInvalid(app.PresentUIAxes);
                app.showInvalid(app.ReducedUIAxes);
            end

            app.StlFileListBox.Enable = app.stlPresense;
            app.RatioSlider.Enable = app.stlPresense;
            app.RatioSpinner.Enable = app.stlPresense;
            app.STLOverwriteButton.Enable = app.stlPresense;
        end
        
        function showInvalid(app, ax) %#ok<INUSD>
            % set 2D view
            plot(ax,0.0)
            view(ax,2);
            % UIAxes range
            xlim(ax, [0 1]);
            ylim(ax, [0 1]);
            % No label
            ax.XTick = [];
            ax.YTick = [];
            ax.XLabel.String = '';
            ax.YLabel.String = '';
            ax.Box = 'on';

            % Align text box at the center of the UIAxes
            text(ax, 0.5, 0.5, 'invalid', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 14);
        end
        
        function bigFile = checkFileSize(app, stlFileName) %#ok<INUSD>
            stlFile = dir(stlFileName); % get stl file name
            if stlFile.bytes > 10e6 % If > 10 MB, big
                bigFile = true;
            else
                bigFile = false;
            end

        end
        
        function progDialog = showProgressDialog(app)
            % show progress dialog
            progDialog = uiprogressdlg(app.UIFigure,'Title','In progress','Message','Wait a whole...','Indeterminate','on');
        end

        function closeProgressDialog(app, progDialog) %#ok<INUSD>
            if isempty(progDialog)
                return
            end

            % close progress dialog
            close(progDialog);
        end

        
        function updateStlFileSelection(app)
            if ~app.StlFileListBox.Enable
                return
            end

            value = app.StlFileListBox.Value;

            filename = fullfile(app.folder, value); %#ok<ADPROP>
            ratio = app.ratio; %#ok<ADPROP>
            app.bigFile = app.checkFileSize(filename); %#ok<ADPROP>

            if app.bigFile
                progDialog = app.showProgressDialog();
            else
                progDialog = [];
            end

            results = app.stlread(filename); %#ok<ADPROP,ADPROP>
            if ~results
                app.showInvalid(app.PresentUIAxes);
                app.showInvalid(app.ReducedUIAxes);
                app.closeProgressDialog(progDialog);
                return
            end
            results = app.showPresentTriangulation();
            if ~results
                app.showInvalid(app.ReducedUIAxes);
                app.closeProgressDialog(progDialog);
                return
            end
            results = app.stlreduce(ratio); %#ok<ADPROP>
            if ~results
                app.showInvalid(app.ReducedUIAxes);
                app.closeProgressDialog(progDialog);
                return
            end
            app.showReducedTriangluation();
            app.closeProgressDialog(progDialog);
        end
        
        function ratioChanging(app, ratio)
            app.ratio = ratio;
            results = app.stlreduce(ratio);
            if ~results
                app.showInvalid(app.ReducedUIAxes);
                return
            end
            presentView = app.ReducedUIAxes.View;
            app.showReducedTriangluation();
            app.ReducedUIAxes.View = presentView;
        end
        
        function ratioChanged(app, ratio, progDialog)
            app.ratio = ratio;
            results = app.stlreduce(ratio);

            if ~results
                app.showInvalid(app.ReducedUIAxes);
                app.closeProgressDialog(progDialog);
                return
            end
            presentView = app.ReducedUIAxes.View;
            app.showReducedTriangluation();
            app.ReducedUIAxes.View = presentView;
            app.closeProgressDialog(progDialog);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ratio = app.RatioSlider.Value;
            app.folder = pwd;
            app.bigFile = true;

            app.updateFileList(app.folder);
            app.updateStlFileSelection();
        end

        % Button pushed function: FolderSelectButton
        function FolderSelectButtonPushed(app, event)
            path = app.folder;

            folderPath = uigetdir(path); % Select Folder by GUI
            if folderPath == 0
                % If cancel, do nothing
                return;
            end
            
            app.updateFileList(folderPath);
            app.updateStlFileSelection();
        end

        % Value changed function: StlFileListBox
        function StlFileListBoxValueChanged(app, event)
            app.updateStlFileSelection();
        end

        % Value changing function: RatioSlider
        function RatioSliderValueChanging(app, event)
            changingValue = event.Value;

            if ~app.bigFile
                % if small file, instantly update

                ratio = changingValue; %#ok<ADPROPLC>
                if ratio < 0.001 %#ok<ADPROPLC>
                    ratio = 0.001; %#ok<ADPROPLC,ADPROPLC>
                end
                app.RatioSpinner.Value = ratio; %#ok<ADPROPLC>

                app.ratioChanging(ratio); %#ok<ADPROPLC>
            end
        end

        % Value changed function: RatioSlider
        function RatioSliderValueChanged(app, event)
            value = app.RatioSlider.Value;
            
            if app.bigFile
                % if big file, only update the display after the value of ratio has been determined

                ratio = value; %#ok<ADPROPLC>
                if ratio < 0.0001 %#ok<ADPROPLC>
                    ratio = 0.0001; %#ok<ADPROPLC>
                end
                app.RatioSpinner.Value = ratio; %#ok<ADPROPLC>

                progDialog = app.showProgressDialog();

                app.ratioChanged(ratio, progDialog); %#ok<ADPROPLC>
            end
        end

        % Value changed function: ShowPresentCheckBox
        function ShowPresentCheckBoxValueChanged(app, event)
            value = app.ShowPresentCheckBox.Value; %#ok<NASGU>
            
            if app.bigFile
                progDialog = app.showProgressDialog();
            else
                progDialog = [];
            end

            results = app.showPresentTriangulation();
            if ~results
                app.showInvalid(app.ReducedUIAxes);
                app.closeProgressDialog(progDialog);
                return
            end
            app.closeProgressDialog(progDialog);
        end

        % Button pushed function: STLOverwriteButton
        function STLOverwriteButtonPushed(app, event)
            app.stlwrite();
            app.updateStlFileSelection();
        end

        % Value changing function: RatioSpinner
        function RatioSpinnerValueChanging(app, event)
            changingValue = event.Value;

            if ~app.bigFile
                % if small file, instantly update
                ratio = changingValue; %#ok<ADPROPLC>
                if ratio < 0.001 %#ok<ADPROPLC,ADPROPLC>
                    ratio = 0.001; %#ok<ADPROPLC>
                end
                app.RatioSlider.Value = ratio; %#ok<ADPROPLC>

                app.ratioChanging(ratio); %#ok<ADPROPLC>
            end
        end

        % Value changed function: RatioSpinner
        function RatioSpinnerValueChanged(app, event)
            value = app.RatioSpinner.Value;
            
            if app.bigFile
                % if big file, only update the display after the value of ratio has been determined

                ratio = value; %#ok<ADPROPLC,ADPROPLC>
                if ratio < 0.0001 %#ok<ADPROPLC>
                    ratio = 0.0001; %#ok<ADPROPLC>
                end
                app.RatioSlider.Value = ratio; %#ok<ADPROPLC>

                progDialog = app.showProgressDialog();

                app.ratioChanged(ratio, progDialog); %#ok<ADPROPLC,ADPROPLC>
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.FolderSelectButton = uibutton(app.GridLayout2, 'push');
            app.FolderSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelectButtonPushed, true);
            app.FolderSelectButton.Text = 'Folder Select';
            app.StlFileListBox = uilistbox(app.GridLayout2);
            app.StlFileListBox.ValueChangedFcn = createCallbackFcn(app, @StlFileListBoxValueChanged, true);
            app.ShowPresentCheckBox = uicheckbox(app.GridLayout2);
            app.ShowPresentCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPresentCheckBoxValueChanged, true);
            app.ShowPresentCheckBox.Text = 'Show Present';
            app.ShowPresentCheckBox.Value = true;
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PresentUIAxes = uiaxes(app.GridLayout3);
            title(app.PresentUIAxes, 'Present')
            xlabel(app.PresentUIAxes, 'X')
            ylabel(app.PresentUIAxes, 'Y')
            zlabel(app.PresentUIAxes, 'Z')
            app.ReducedUIAxes = uiaxes(app.GridLayout3);
            title(app.ReducedUIAxes, 'Reduced')
            xlabel(app.ReducedUIAxes, 'X')
            ylabel(app.ReducedUIAxes, 'Y')
            zlabel(app.ReducedUIAxes, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.RatioSlider = uislider(app.GridLayout4);
            app.RatioSlider.Limits = [0 1];
            app.RatioSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.RatioSlider.ValueChangedFcn = createCallbackFcn(app, @RatioSliderValueChanged, true);
            app.RatioSlider.ValueChangingFcn = createCallbackFcn(app, @RatioSliderValueChanging, true);
            app.RatioSlider.Value = 0.1;
            app.STLOverwriteButton = uibutton(app.GridLayout4, 'push');
            app.STLOverwriteButton.ButtonPushedFcn = createCallbackFcn(app, @STLOverwriteButtonPushed, true);
            app.STLOverwriteButton.Text = 'STL Overwrite';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.RatioSpinner = uispinner(app.GridLayout5);
            app.RatioSpinner.Step = 0.01;
            app.RatioSpinner.ValueChangingFcn = createCallbackFcn(app, @RatioSpinnerValueChanging, true);
            app.RatioSpinner.Limits = [0.0001 1];
            app.RatioSpinner.ValueDisplayFormat = '%1.4g';
            app.RatioSpinner.ValueChangedFcn = createCallbackFcn(app, @RatioSpinnerValueChanged, true);
            app.RatioSpinner.Value = 0.1;
            app.RatioSpinnerLabel = uilabel(app.GridLayout5);
            app.RatioSpinnerLabel.Text = 'Ratio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StlResolutionReducer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end